classdef GUI_carboncycle < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        SimulationofForestIndustrysCO2ImpactUIFigure  matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        LeftPanel                       matlab.ui.container.Panel
        SimulationDetailsPanel          matlab.ui.container.Panel
        FinalfellingofannualharvestingEditFieldLabel  matlab.ui.control.Label
        FinalfellingofannualharvestingEditField  matlab.ui.control.NumericEditField
        SimulationtimeyearsLabel        matlab.ui.control.Label
        SimulationtimeyearsSlider       matlab.ui.control.Slider
        HarvestinglimitoftotalforestEditFieldLabel  matlab.ui.control.Label
        HarvestinglimitoftotalforestEditField  matlab.ui.control.NumericEditField
        InitialDemandsperyearPanel      matlab.ui.container.Panel
        Sawnwoodm3EditFieldLabel        matlab.ui.control.Label
        Sawnwoodm3EditField             matlab.ui.control.NumericEditField
        Plywoodm3EditFieldLabel         matlab.ui.control.Label
        Plywoodm3EditField              matlab.ui.control.NumericEditField
        PackagingpapertonnessubstitutedbyLDPEEditFieldLabel  matlab.ui.control.Label
        PackagingpapertonnessubstitutedbyLDPEEditField  matlab.ui.control.NumericEditField
        PackagingpapertonnessubstitutedbyHDPEEditFieldLabel  matlab.ui.control.Label
        PackagingpapertonnessubstitutedbyHDPEEditField  matlab.ui.control.NumericEditField
        PackagingpapertonnessubstitutedbyglassEditFieldLabel  matlab.ui.control.Label
        PackagingpapertonnessubstitutedbyglassEditField  matlab.ui.control.NumericEditField
        PackagingpapertonnessubstitutedbyaluminiumEditFieldLabel  matlab.ui.control.Label
        PackagingpapertonnessubstitutedbyaluminiumEditField  matlab.ui.control.NumericEditField
        GraphicpapertonnesEditFieldLabel  matlab.ui.control.Label
        GraphicpapertonnesEditField     matlab.ui.control.NumericEditField
        TissuepapertonnesEditFieldLabel  matlab.ui.control.Label
        TissuepapertonnesEditField      matlab.ui.control.NumericEditField
        DefaultvaluesLabel              matlab.ui.control.Label
        DemandSwitch                    matlab.ui.control.Switch
        RunthesimulationButton          matlab.ui.control.Button
        ForestDetailsPanel              matlab.ui.container.Panel
        InitialvolumeMm3EditFieldLabel  matlab.ui.control.Label
        InitialvolumeMm3EditField       matlab.ui.control.NumericEditField
        AreaMhaEditFieldLabel           matlab.ui.control.Label
        AreaMhaEditField                matlab.ui.control.NumericEditField
        InitialageyearsEditFieldLabel   matlab.ui.control.Label
        InitialageyearsEditField        matlab.ui.control.NumericEditField
        ForentireFinnishforestryusethedefaultvaluesbelowLabel  matlab.ui.control.Label
        RightPanel                      matlab.ui.container.Panel
        TabGroup2                       matlab.ui.container.TabGroup
        MainPlotsTab                    matlab.ui.container.Tab
        PrincipalInterestUIAxes         matlab.ui.control.UIAxes
        PrincipalInterestUIAxes_14      matlab.ui.control.UIAxes
        PrincipalInterestUIAxes_16      matlab.ui.control.UIAxes
        PrincipalInterestUIAxes_2       matlab.ui.control.UIAxes
        ExogenousCO2EnergyTab           matlab.ui.container.Tab
        PrincipalInterestUIAxes_6       matlab.ui.control.UIAxes
        PrincipalInterestUIAxes_5       matlab.ui.control.UIAxes
        PrincipalInterestUIAxes_4       matlab.ui.control.UIAxes
        PrincipalInterestUIAxes_17      matlab.ui.control.UIAxes
        ProductDemandandSupplyTab       matlab.ui.container.Tab
        PrincipalInterestUIAxes_20      matlab.ui.control.UIAxes
        PrincipalInterestUIAxes_21      matlab.ui.control.UIAxes
        PrincipalInterestUIAxes_22      matlab.ui.control.UIAxes
        PrincipalInterestUIAxes_23      matlab.ui.control.UIAxes
        InformationTab                  matlab.ui.container.Tab
        RecyclingRatesfromTilastokeskus2018Panel  matlab.ui.container.Panel
        HarvestinglimitoftotalforestEditFieldLabel_2  matlab.ui.control.Label
        HarvestinglimitoftotalforestEditFieldLabel_18  matlab.ui.control.Label
        HarvestinglimitoftotalforestEditFieldLabel_22  matlab.ui.control.Label
        HarvestinglimitoftotalforestEditFieldLabel_23  matlab.ui.control.Label
        HarvestinglimitoftotalforestEditFieldLabel_24  matlab.ui.control.Label
        HarvestinglimitoftotalforestEditFieldLabel_25  matlab.ui.control.Label
        HarvestinglimitoftotalforestEditFieldLabel_26  matlab.ui.control.Label
        HarvestinglimitoftotalforestEditFieldLabel_27  matlab.ui.control.Label
        HarvestinglimitoftotalforestEditFieldLabel_28  matlab.ui.control.Label
        HarvestinglimitoftotalforestEditFieldLabel_29  matlab.ui.control.Label
        HarvestinglimitoftotalforestEditFieldLabel_30  matlab.ui.control.Label
        HarvestinglimitoftotalforestEditFieldLabel_31  matlab.ui.control.Label
        HarvestinglimitoftotalforestEditFieldLabel_32  matlab.ui.control.Label
        HarvestinglimitoftotalforestEditFieldLabel_33  matlab.ui.control.Label
        HarvestinglimitoftotalforestEditFieldLabel_34  matlab.ui.control.Label
        HarvestinglimitoftotalforestEditFieldLabel_35  matlab.ui.control.Label
        HarvestinglimitoftotalforestEditFieldLabel_36  matlab.ui.control.Label
        HarvestinglimitoftotalforestEditFieldLabel_37  matlab.ui.control.Label
        HarvestinglimitoftotalforestEditFieldLabel_38  matlab.ui.control.Label
        HarvestinglimitoftotalforestEditFieldLabel_39  matlab.ui.control.Label
        HarvestinglimitoftotalforestEditFieldLabel_40  matlab.ui.control.Label
        HarvestinglimitoftotalforestEditFieldLabel_41  matlab.ui.control.Label
        HarvestinglimitoftotalforestEditFieldLabel_42  matlab.ui.control.Label
        HarvestinglimitoftotalforestEditFieldLabel_43  matlab.ui.control.Label
        RecyclingEmissionsfromDahlboetal2011Panel  matlab.ui.container.Panel
        HarvestinglimitoftotalforestEditFieldLabel_44  matlab.ui.control.Label
        HarvestinglimitoftotalforestEditFieldLabel_45  matlab.ui.control.Label
        HarvestinglimitoftotalforestEditFieldLabel_46  matlab.ui.control.Label
        HarvestinglimitoftotalforestEditFieldLabel_47  matlab.ui.control.Label
        HarvestinglimitoftotalforestEditFieldLabel_48  matlab.ui.control.Label
        HarvestinglimitoftotalforestEditFieldLabel_49  matlab.ui.control.Label
        HarvestinglimitoftotalforestEditFieldLabel_50  matlab.ui.control.Label
        HarvestinglimitoftotalforestEditFieldLabel_53  matlab.ui.control.Label
        HarvestinglimitoftotalforestEditFieldLabel_54  matlab.ui.control.Label
        HarvestinglimitoftotalforestEditFieldLabel_55  matlab.ui.control.Label
        HarvestinglimitoftotalforestEditFieldLabel_56  matlab.ui.control.Label
        HarvestinglimitoftotalforestEditFieldLabel_57  matlab.ui.control.Label
        HarvestinglimitoftotalforestEditFieldLabel_58  matlab.ui.control.Label
        HarvestinglimitoftotalforestEditFieldLabel_59  matlab.ui.control.Label
        HarvestinglimitoftotalforestEditFieldLabel_60  matlab.ui.control.Label
        HarvestinglimitoftotalforestEditFieldLabel_61  matlab.ui.control.Label
        HarvestinglimitoftotalforestEditFieldLabel_62  matlab.ui.control.Label
        Datafromexpertsfortheyear2019Panel  matlab.ui.container.Panel
        HarvestinglimitoftotalforestEditFieldLabel_63  matlab.ui.control.Label
        HarvestinglimitoftotalforestEditFieldLabel_64  matlab.ui.control.Label
        HarvestinglimitoftotalforestEditFieldLabel_65  matlab.ui.control.Label
        HarvestinglimitoftotalforestEditFieldLabel_66  matlab.ui.control.Label
        HarvestinglimitoftotalforestEditFieldLabel_67  matlab.ui.control.Label
        HarvestinglimitoftotalforestEditFieldLabel_69  matlab.ui.control.Label
        HarvestinglimitoftotalforestEditFieldLabel_87  matlab.ui.control.Label
        HarvestinglimitoftotalforestEditFieldLabel_88  matlab.ui.control.Label
        HarvestinglimitoftotalforestEditFieldLabel_89  matlab.ui.control.Label
        HarvestinglimitoftotalforestEditFieldLabel_90  matlab.ui.control.Label
        HarvestinglimitoftotalforestEditFieldLabel_91  matlab.ui.control.Label
        HarvestinglimitoftotalforestEditFieldLabel_92  matlab.ui.control.Label
        HarvestinglimitoftotalforestEditFieldLabel_93  matlab.ui.control.Label
        HarvestinglimitoftotalforestEditFieldLabel_94  matlab.ui.control.Label
        HarvestinglimitoftotalforestEditFieldLabel_95  matlab.ui.control.Label
        HarvestinglimitoftotalforestEditFieldLabel_96  matlab.ui.control.Label
        HarvestinglimitoftotalforestEditFieldLabel_97  matlab.ui.control.Label
        HarvestinglimitoftotalforestEditFieldLabel_98  matlab.ui.control.Label
        HarvestinglimitoftotalforestEditFieldLabel_99  matlab.ui.control.Label
        HarvestinglimitoftotalforestEditFieldLabel_100  matlab.ui.control.Label
        HarvestinglimitoftotalforestEditFieldLabel_101  matlab.ui.control.Label
        HarvestinglimitoftotalforestEditFieldLabel_102  matlab.ui.control.Label
        HarvestinglimitoftotalforestEditFieldLabel_103  matlab.ui.control.Label
        HarvestinglimitoftotalforestEditFieldLabel_104  matlab.ui.control.Label
        HarvestinglimitoftotalforestEditFieldLabel_105  matlab.ui.control.Label
        HarvestinglimitoftotalforestEditFieldLabel_106  matlab.ui.control.Label
        HarvestinglimitoftotalforestEditFieldLabel_107  matlab.ui.control.Label
        HarvestinglimitoftotalforestEditFieldLabel_108  matlab.ui.control.Label
        HarvestinglimitoftotalforestEditFieldLabel_109  matlab.ui.control.Label
        HarvestinglimitoftotalforestEditFieldLabel_110  matlab.ui.control.Label
        HarvestinglimitoftotalforestEditFieldLabel_111  matlab.ui.control.Label
        HarvestinglimitoftotalforestEditFieldLabel_112  matlab.ui.control.Label
        HarvestinglimitoftotalforestEditFieldLabel_113  matlab.ui.control.Label
        HarvestinglimitoftotalforestEditFieldLabel_114  matlab.ui.control.Label
        HarvestinglimitoftotalforestEditFieldLabel_115  matlab.ui.control.Label
        HarvestinglimitoftotalforestEditFieldLabel_116  matlab.ui.control.Label
        HarvestinglimitoftotalforestEditFieldLabel_117  matlab.ui.control.Label
        HarvestinglimitoftotalforestEditFieldLabel_118  matlab.ui.control.Label
        HarvestinglimitoftotalforestEditFieldLabel_119  matlab.ui.control.Label
        HarvestinglimitoftotalforestEditFieldLabel_120  matlab.ui.control.Label
        HarvestinglimitoftotalforestEditFieldLabel_121  matlab.ui.control.Label
        HarvestinglimitoftotalforestEditFieldLabel_122  matlab.ui.control.Label
        HarvestinglimitoftotalforestEditFieldLabel_123  matlab.ui.control.Label
        HarvestinglimitoftotalforestEditFieldLabel_124  matlab.ui.control.Label
        HarvestinglimitoftotalforestEditFieldLabel_125  matlab.ui.control.Label
        HarvestinglimitoftotalforestEditFieldLabel_126  matlab.ui.control.Label
        HarvestinglimitoftotalforestEditFieldLabel_127  matlab.ui.control.Label
        HarvestinglimitoftotalforestEditFieldLabel_128  matlab.ui.control.Label
        HarvestinglimitoftotalforestEditFieldLabel_129  matlab.ui.control.Label
        HarvestinglimitoftotalforestEditFieldLabel_130  matlab.ui.control.Label
        HarvestinglimitoftotalforestEditFieldLabel_131  matlab.ui.control.Label
        HarvestinglimitoftotalforestEditFieldLabel_132  matlab.ui.control.Label
        HarvestinglimitoftotalforestEditFieldLabel_133  matlab.ui.control.Label
        HarvestinglimitoftotalforestEditFieldLabel_134  matlab.ui.control.Label
        HarvestinglimitoftotalforestEditFieldLabel_135  matlab.ui.control.Label
        HarvestinglimitoftotalforestEditFieldLabel_136  matlab.ui.control.Label
        HarvestinglimitoftotalforestEditFieldLabel_137  matlab.ui.control.Label
        HarvestinglimitoftotalforestEditFieldLabel_138  matlab.ui.control.Label
        HarvestinglimitoftotalforestEditFieldLabel_139  matlab.ui.control.Label
        HarvestinglimitoftotalforestEditFieldLabel_140  matlab.ui.control.Label
        HarvestinglimitoftotalforestEditFieldLabel_141  matlab.ui.control.Label
        HarvestinglimitoftotalforestEditFieldLabel_142  matlab.ui.control.Label
        HarvestinglimitoftotalforestEditFieldLabel_143  matlab.ui.control.Label
        HarvestinglimitoftotalforestEditFieldLabel_144  matlab.ui.control.Label
        HarvestinglimitoftotalforestEditFieldLabel_145  matlab.ui.control.Label
        HarvestinglimitoftotalforestEditFieldLabel_146  matlab.ui.control.Label
        HarvestinglimitoftotalforestEditFieldLabel_147  matlab.ui.control.Label
        HarvestinglimitoftotalforestEditFieldLabel_148  matlab.ui.control.Label
        HarvestinglimitoftotalforestEditFieldLabel_149  matlab.ui.control.Label
        HarvestinglimitoftotalforestEditFieldLabel_150  matlab.ui.control.Label
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    methods (Access = private)
        
        % This function changes the appearance of the app and the text
        %  of the "run the simulation" -button while simulating.
        function toggleUIC(app, state, label)
            % toggle the state of the uicontrols in the app
            uics = findall(app.SimulationofForestIndustrysCO2ImpactUIFigure);
            for idx = 1:length(uics)
               uic = uics(idx);
               if isprop(uic,'Enable')
                   uic.Enable = state;
               end
            end
            % update the simulate button text
            app.RunthesimulationButton.Text = label;
            drawnow; % flush the ui events queue
        end % toggleUI
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % load simulink to pay the one time cost of loading libraries
            % during app startup.
            start_simulink;
            
        %%% THIS FUNCTION WAS IN MATLAB'S EXAMPLE.
        %%% DON'T KNOW WHAT IT DOES EXACTLY.
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.SimulationofForestIndustrysCO2ImpactUIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {721, 721};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {291, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end

        % Button pushed function: RunthesimulationButton
        function RunTheSimulationButtonPushed(app, event)
                        
            %%% This function runs the simulation using init_upm()-function.
            %%% The parameters are from the GUI's inputs.
            %%% This function also plots the simulink outports.
            
            
            % Change "Run the simulation" button text into "Simulating..." while simulating
            app.toggleUIC('off', 'Simulating ...');
            
            
            %%% Set parameters for the simulation function. The parameter
            %%% values are given by the user. The simulation can also be
            %%% run with the default values.
            
            % Set simulation time
            t_simulation = app.SimulationtimeyearsSlider.Value;
            
            % Set initial product demands
            dem_sawn = app.Sawnwoodm3EditField.Value;       % sawn wood      
            dem_ply = app.Plywoodm3EditField.Value;         % plywood
            dem_pack_1 = app.PackagingpapertonnessubstitutedbyLDPEEditField.Value;       % packaging papers
            dem_pack_2 = app.PackagingpapertonnessubstitutedbyHDPEEditField.Value;
            dem_pack_3 = app.PackagingpapertonnessubstitutedbyglassEditField.Value;
            dem_pack_4 = app.PackagingpapertonnessubstitutedbyaluminiumEditField.Value;
            dem_graph = app.GraphicpapertonnesEditField.Value;    % graphic paper
            dem_tis = app.TissuepapertonnesEditField.Value;       % tissue paper
            demand_0 = [dem_sawn;
                        dem_ply;
                        dem_pack_1;
                        dem_pack_2;
                        dem_pack_3;
                        dem_pack_4;
                        dem_graph;
                        dem_tis];       % create a matrix for initial demands
            
            % Choose how the future demands are defined. Use either
            % constant demands or predicted demands
            if strcmp(app.DemandSwitch.Value, 'Constant demands')
                use_demand_predictions = 0;
            else
                use_demand_predictions = 1;
            end
            
            % Set sustainable harvesting limit and final felling values
            k_sustainable_harvesting = app.HarvestinglimitoftotalforestEditField.Value / 100;
            k_final_felling = app.FinalfellingofannualharvestingEditField.Value/100;
            
            % Set forest details
            forest_volume_0 = app.InitialvolumeMm3EditField.Value  * 10^6;
            forest_area = app.AreaMhaEditField.Value * 10^6;
            forest_age_0 = app.InitialageyearsEditField.Value;


            %%% Run simulation with init_carboncycle -function
            %%% using the harvesting limit set by user.
            simulation_output = init_carboncycle(t_simulation, demand_0, ...
                use_demand_predictions, k_sustainable_harvesting, k_final_felling, ...
                forest_volume_0, forest_area, forest_age_0);
            
            
            %%% Repeat simulation with no harvesting.
            % In this simulation, there is no forest industry but only
            % substitute products. In practice, this is done by setting the
            % harvesting limit value as 0.
            simulation_output_1 = init_carboncycle(t_simulation, demand_0, ...
                use_demand_predictions, 0, k_final_felling, ...
                forest_volume_0, forest_area, forest_age_0);            


            %%% Set values that we want to plot (with forest products)
            % The dimension of the simulink outports is sometimes 1 x 1 x n.
            % Thus, use function 'squeeze'.
            % More outputs can be extracted similarly, see the slx-file
            % for indices.
            total_demand = squeeze(simulation_output.yout.signals(7).values);           % total product demands    
            forest_supply = squeeze(simulation_output.yout.signals(14).values);         % forest product supplies
            forest = squeeze(simulation_output.yout.signals(2).values);                 % Finnish forest volume
            drain = squeeze(simulation_output.yout.signals(18).values);                 % annual drain
            average_age = squeeze(simulation_output.yout.signals(17).values);           % average forest age
            atmco2 = squeeze(simulation_output.yout.signals(1).values);                 % atmospheric CO2
            energy_recovered = squeeze(simulation_output.yout.signals(3).values);       % recovered energy in kWh
            exogenous_co2 = squeeze(simulation_output.yout.signals(4).values);          % CO2 emissions released in manufacturing and waste handling                                                                                       
            incineration_co2 = squeeze(simulation_output.yout.signals(5).values);       % CO2 released in incineration (recycling)
            energy_recovery_co2 = squeeze(simulation_output.yout.signals(6).values);    % CO2 released in energy recovery (recycling)
            time = simulation_output.yout.time;                                         % simulation time

            
            %%% OTHER POSSIBLE OUTPUTS TO PLOT
            %%% NOT USED IN THE GUI
            %forest_demand = squeeze(simulation_output.yout.signals(8).values); 
            %sub_demand = squeeze(simulation_output.yout.signals(9).values);
            %recycled_forest_supply = squeeze(simulation_output.yout.signals(10).values);
            %recycled_sub_supply = squeeze(simulation_output.yout.signals(11).values);
            %raw_wood_demand = squeeze(simulation_output.yout.signals(12).values);
            %raw_wood_supply = squeeze(simulation_output.yout.signals(13).values);
            %forest_supply_delayed = squeeze(simulation_output.yout.signals(15).values);            
            %sub_supply = squeeze(simulation_output.yout.signals(16).values);       
            
                                                            
            %%% Set values that we want to plot (only substitute
            %%% products)
            % The dimension of the simulink outports is sometimes1 x 1 x n.
            % Thus, use function 'squeeze'.
            forest_1 = squeeze(simulation_output_1.yout.signals(2).values);                 % Finnish forest volume
            drain_1 = squeeze(simulation_output_1.yout.signals(18).values);                 % annual drain
            average_age_1 = squeeze(simulation_output_1.yout.signals(17).values);           % average forest age
            atmco2_1 = squeeze(simulation_output_1.yout.signals(1).values);                 % atmospheric CO2
            energy_recovered_1 = squeeze(simulation_output_1.yout.signals(3).values);       % recovered energy in kWh
            exogenous_co2_1 = squeeze(simulation_output_1.yout.signals(4).values);          % CO2 emissions released in manufacturing and waste handling
            incineration_co2_1 = squeeze(simulation_output_1.yout.signals(5).values);       % CO2 released in incineration (recycling)
            energy_recovery_co2_1 = squeeze(simulation_output_1.yout.signals(6).values);    % CO2 released in energy recovery (recycling)
            time_1 = simulation_output_1.yout.time;                                         % simulation time


            %%% Plot the figures (with forest products)
            % The figures are referred to as 'app.PrincipalInterestUIAxes_X'.
            % Check the Design View for the numbering.
            % The first parameter is the simulation time and the second
            % parameter is the variable that we want to plot.
            % Function 'hold' should be used if you want to plot several graphs into same figure.
            % Functions 'xticks' and 'xticklabels' create ticks and their labels to the x-axis.
            % The simulation starts from 2019. The x-axis ticks are set to start from 2020
            % for a nicer visual layout. The y-axis is automated.
            
            % Plot the Finnish forest volume
            plot(app.PrincipalInterestUIAxes, time, forest);
            hold(app.PrincipalInterestUIAxes, 'on');
            xticks(app.PrincipalInterestUIAxes, 1:(t_simulation/10):t_simulation);                  % Set x-axis ticks
            xticklabels(app.PrincipalInterestUIAxes, 2020:(t_simulation/10):(2020+t_simulation));   % Set x-axis tick labels
            
            % Plot the annual drain (harvesting + natural loss)
            plot(app.PrincipalInterestUIAxes_16, time, drain);
            hold(app.PrincipalInterestUIAxes_16, 'on');
            xticks(app.PrincipalInterestUIAxes_16, 1:(t_simulation/10):t_simulation);
            xticklabels(app.PrincipalInterestUIAxes_16, 2020:(t_simulation/10):(2020+t_simulation));           
            
            % Plot the forest age
            plot(app.PrincipalInterestUIAxes_14, time, average_age)
            hold(app.PrincipalInterestUIAxes_14, 'on');
            xticks(app.PrincipalInterestUIAxes_14, 1:(t_simulation/10):t_simulation);    
            xticklabels(app.PrincipalInterestUIAxes_14, 2020:(t_simulation/10):(2020+t_simulation));                  
            
            % Plot the atmospheric CO2
            plot(app.PrincipalInterestUIAxes_2, time, atmco2);
            hold(app.PrincipalInterestUIAxes_2, 'on');
            xticks(app.PrincipalInterestUIAxes_2, 1:(t_simulation/10):t_simulation);    
            xticklabels(app.PrincipalInterestUIAxes_2, 2020:(t_simulation/10):(2020+t_simulation));
            
            % Plot the exogenous CO2 (manufacturing and waste handling CO2 emissions)
            plot(app.PrincipalInterestUIAxes_5, time, exogenous_co2);
            hold(app.PrincipalInterestUIAxes_5, 'on');            
            xticks(app.PrincipalInterestUIAxes_5, 1:(t_simulation/10):t_simulation);    
            xticklabels(app.PrincipalInterestUIAxes_5, 2020:(t_simulation/10):(2020+t_simulation));
            
            % Plot the energy recovery (CO2 released from burning waste)
            plot(app.PrincipalInterestUIAxes_6, time, energy_recovery_co2);
            hold(app.PrincipalInterestUIAxes_6, 'on');
            xticks(app.PrincipalInterestUIAxes_6, 1:(t_simulation/10):t_simulation);    
            xticklabels(app.PrincipalInterestUIAxes_6, 2020:(t_simulation/10):(2020+t_simulation));
            
            % Plot the incineration (CO2 released from burning waste)
            plot(app.PrincipalInterestUIAxes_17, time, incineration_co2);
            hold(app.PrincipalInterestUIAxes_17, 'on');
            xticks(app.PrincipalInterestUIAxes_17, 1:(t_simulation/10):t_simulation);    
            xticklabels(app.PrincipalInterestUIAxes_17, 2020:(t_simulation/10):(2020+t_simulation));            
            
            % Plot the recovered energy in kWh
            plot(app.PrincipalInterestUIAxes_4, time, energy_recovered);
            hold(app.PrincipalInterestUIAxes_4, 'on');            
            xticks(app.PrincipalInterestUIAxes_4, 1:(t_simulation/10):t_simulation);
            xticklabels(app.PrincipalInterestUIAxes_4, 2020:(t_simulation/10):(2020+t_simulation));                      


            %%% Plot the figures (only substitute products)
            
            % Plot the forest volume
            plot(app.PrincipalInterestUIAxes, time_1, forest_1);
            hold(app.PrincipalInterestUIAxes, 'off');
            legend(app.PrincipalInterestUIAxes, 'With Forest Products','Only Substitute Products', 'Location', 'southwest');
            
            % Plot the annual drain (harvesting + natural loss)
            plot(app.PrincipalInterestUIAxes_16, time_1, drain_1);
            hold(app.PrincipalInterestUIAxes_16, 'off');
            legend(app.PrincipalInterestUIAxes_16, 'With Forest Products','Only Substitute Products', 'Location', 'west');
            
            % Plot the forest age
            plot(app.PrincipalInterestUIAxes_14, time_1, average_age_1)
            hold(app.PrincipalInterestUIAxes_14, 'off');
            legend(app.PrincipalInterestUIAxes_14, 'With Forest Products','Only Substitute Products', 'Location', 'northwest');
            
            % Plot the atmospheric CO2
            plot(app.PrincipalInterestUIAxes_2, time_1, atmco2_1);
            hold(app.PrincipalInterestUIAxes_2, 'off');
            legend(app.PrincipalInterestUIAxes_2, 'With Forest Products','Only Substitute Products', 'Location', 'northwest');
            
            % Plot the exogenous CO2 (manufacturing and waste handling)
            plot(app.PrincipalInterestUIAxes_5, time_1, exogenous_co2_1);
            hold(app.PrincipalInterestUIAxes_5, 'off');            
            legend(app.PrincipalInterestUIAxes_5, 'With Forest Products','Only Substitute Products', 'Location', 'northwest');

            
            % Plot the energy recovery (waste burning CO2)
            plot(app.PrincipalInterestUIAxes_6, time_1, energy_recovery_co2_1);
            hold(app.PrincipalInterestUIAxes_6, 'off');
            legend(app.PrincipalInterestUIAxes_6, 'With Forest Products','Only Substitute Products', 'Location', 'northwest');
            
            % Plot the incineration (waste burning CO2)
            plot(app.PrincipalInterestUIAxes_17, time_1, incineration_co2_1);
            hold(app.PrincipalInterestUIAxes_17, 'off');
            legend(app.PrincipalInterestUIAxes_17, 'With Forest Products','Only Substitute Products', 'Location', 'northwest');            
            
            % Plot the recovered energy in kWh
            plot(app.PrincipalInterestUIAxes_4, time_1, energy_recovered_1);
            hold(app.PrincipalInterestUIAxes_4, 'off');            
            legend(app.PrincipalInterestUIAxes_4, 'With Forest Products','Only Substitute Products', 'Location', 'northwest');            


            %%% PLOT FOREST PRODUCT DEMANDS AND SUPPLIES (when forest products are used).
            %%% Note that for demands, different products correspond to
            %%% different columns, and for supplies different products
            %%% correspond to different rows.
            
            % Plot the wood demands and supplies (in m3)          
            plot(app.PrincipalInterestUIAxes_20, time, total_demand(:,1), 'b');       % sawn wood demand
            hold(app.PrincipalInterestUIAxes_20, 'on')              
            plot(app.PrincipalInterestUIAxes_20, time, forest_supply(1,:), 'b--');    % sawn wood supply
            plot(app.PrincipalInterestUIAxes_20, time, total_demand(:,2), 'r');       % plywood demand
            plot(app.PrincipalInterestUIAxes_20, time, forest_supply(2,:), 'r--');       % plywood demand
            xticks(app.PrincipalInterestUIAxes_20, 1:(t_simulation/10):t_simulation);    
            xticklabels(app.PrincipalInterestUIAxes_20, 2020:(t_simulation/10):(2020+t_simulation));
            legend(app.PrincipalInterestUIAxes_20, 'Sawn Wood Demand', 'Sawn Wood Supply', ...
                                                  'Plywood Demand', 'Plywood Supply', 'Location', 'west')            
            hold(app.PrincipalInterestUIAxes_20, 'off')
            
            % Plot the packaging paper demand and supply (in tonnes)                     
            plot(app.PrincipalInterestUIAxes_21, time, sum(total_demand(:,3:6), 2), 'b');  % Sum all the packaging papers together    
            hold(app.PrincipalInterestUIAxes_21, 'on')               
            plot(app.PrincipalInterestUIAxes_21, time, sum(forest_supply(3:6,:), 1), 'b--');
            xticks(app.PrincipalInterestUIAxes_21, 1:(t_simulation/10):t_simulation);    
            xticklabels(app.PrincipalInterestUIAxes_21, 2020:(t_simulation/10):(2020+t_simulation));
            legend(app.PrincipalInterestUIAxes_21, 'Demand', 'Supply', 'Location', 'northwest')            
            hold(app.PrincipalInterestUIAxes_21, 'off')
            
            % Plot the graphic paper tissue paper demands and supplies (in tonnes)
            plot(app.PrincipalInterestUIAxes_22, time, total_demand(:,7), 'b');
            hold(app.PrincipalInterestUIAxes_22, 'on')                        
            plot(app.PrincipalInterestUIAxes_22, time, forest_supply(7,:), 'b--');
            xticks(app.PrincipalInterestUIAxes_22, 1:(t_simulation/10):t_simulation);    
            xticklabels(app.PrincipalInterestUIAxes_22, 2020:(t_simulation/10):(2020+t_simulation));
            legend(app.PrincipalInterestUIAxes_22, 'Demand', 'Supply', 'Location', 'northeast')            
            hold(app.PrincipalInterestUIAxes_22, 'off')           
            
            % Plot the tissue paper demand and supply (in tonnes)           
            plot(app.PrincipalInterestUIAxes_23, time, total_demand(:,8), 'b'); 
            hold(app.PrincipalInterestUIAxes_23, 'on')             
            plot(app.PrincipalInterestUIAxes_23, time, forest_supply(8,:), 'b--');
            xticks(app.PrincipalInterestUIAxes_23, 1:(t_simulation/10):t_simulation);    
            xticklabels(app.PrincipalInterestUIAxes_23, 2020:(t_simulation/10):(2020+t_simulation));
            legend(app.PrincipalInterestUIAxes_23, 'Demand', 'Supply', 'Location', 'west')            
            hold(app.PrincipalInterestUIAxes_23, 'off')   


            % Change simulation button back to normal.
            app.toggleUIC('on', 'Run the simulation');
            
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.SimulationofForestIndustrysCO2ImpactUIFigure = uifigure('Visible', 'off');
            app.SimulationofForestIndustrysCO2ImpactUIFigure.Name = 'Simulation of Forest Industry''s CO2 Impact';
            app.SimulationofForestIndustrysCO2ImpactUIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.SimulationofForestIndustrysCO2ImpactUIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.SimulationDetailsPanel = uipanel(app.LeftPanel);
            app.SimulationDetailsPanel.Title = 'Simulation Details';
            app.FinalfellingofannualharvestingEditFieldLabel = uilabel(app.SimulationDetailsPanel);
            app.FinalfellingofannualharvestingEditFieldLabel.Text = {'Final felling'; '(% of annual harvesting)'};
            app.FinalfellingofannualharvestingEditField = uieditfield(app.SimulationDetailsPanel, 'numeric');
            app.FinalfellingofannualharvestingEditField.Limits = [0 100];
            app.FinalfellingofannualharvestingEditField.Tooltip = {'Only final felling affects the age of the forest. Rest of the harvesting is thinning.'};
            app.FinalfellingofannualharvestingEditField.Value = 22;
            app.SimulationtimeyearsLabel = uilabel(app.SimulationDetailsPanel);
            app.SimulationtimeyearsLabel.Text = {'Simulation time '; '(years)'};
            app.SimulationtimeyearsSlider = uislider(app.SimulationDetailsPanel);
            app.SimulationtimeyearsSlider.Limits = [0 150];
            app.SimulationtimeyearsSlider.Value = 60;
            app.HarvestinglimitoftotalforestEditFieldLabel = uilabel(app.SimulationDetailsPanel);
            app.HarvestinglimitoftotalforestEditFieldLabel.Text = {'Harvesting limit'; '(% of total forest)'};
            app.HarvestinglimitoftotalforestEditField = uieditfield(app.SimulationDetailsPanel, 'numeric');
            app.HarvestinglimitoftotalforestEditField.Limits = [0 100];
            app.HarvestinglimitoftotalforestEditField.Value = 3.42;
            app.InitialDemandsperyearPanel = uipanel(app.LeftPanel);
            app.InitialDemandsperyearPanel.Title = 'Initial Demands (per year)';
            app.Sawnwoodm3EditFieldLabel = uilabel(app.InitialDemandsperyearPanel);
            app.Sawnwoodm3EditFieldLabel.Text = 'Sawn wood (m3)';
            app.Sawnwoodm3EditField = uieditfield(app.InitialDemandsperyearPanel, 'numeric');
            app.Sawnwoodm3EditField.Limits = [0 Inf];
            app.Sawnwoodm3EditField.ValueDisplayFormat = '%.0f';
            app.Sawnwoodm3EditField.Value = 11330000;
            app.Plywoodm3EditFieldLabel = uilabel(app.InitialDemandsperyearPanel);
            app.Plywoodm3EditFieldLabel.Text = 'Plywood (m3)';
            app.Plywoodm3EditField = uieditfield(app.InitialDemandsperyearPanel, 'numeric');
            app.Plywoodm3EditField.Limits = [0 Inf];
            app.Plywoodm3EditField.ValueDisplayFormat = '%.0f';
            app.Plywoodm3EditField.Value = 1090000;
            app.PackagingpapertonnessubstitutedbyLDPEEditFieldLabel = uilabel(app.InitialDemandsperyearPanel);
            app.PackagingpapertonnessubstitutedbyLDPEEditFieldLabel.Text = {'Packaging paper (tonnes), '; 'substituted by LDPE'};
            app.PackagingpapertonnessubstitutedbyLDPEEditField = uieditfield(app.InitialDemandsperyearPanel, 'numeric');
            app.PackagingpapertonnessubstitutedbyLDPEEditField.Limits = [0 Inf];
            app.PackagingpapertonnessubstitutedbyLDPEEditField.ValueDisplayFormat = '%.0f';
            app.PackagingpapertonnessubstitutedbyLDPEEditField.Value = 24000000;
            app.PackagingpapertonnessubstitutedbyHDPEEditFieldLabel = uilabel(app.InitialDemandsperyearPanel);
            app.PackagingpapertonnessubstitutedbyHDPEEditFieldLabel.Text = {'Packaging paper (tonnes), '; 'substituted by HDPE'};
            app.PackagingpapertonnessubstitutedbyHDPEEditField = uieditfield(app.InitialDemandsperyearPanel, 'numeric');
            app.PackagingpapertonnessubstitutedbyHDPEEditField.Limits = [0 Inf];
            app.PackagingpapertonnessubstitutedbyHDPEEditField.ValueDisplayFormat = '%.0f';
            app.PackagingpapertonnessubstitutedbyHDPEEditField.Value = 24000000;
            app.PackagingpapertonnessubstitutedbyglassEditFieldLabel = uilabel(app.InitialDemandsperyearPanel);
            app.PackagingpapertonnessubstitutedbyglassEditFieldLabel.Text = {'Packaging paper (tonnes), '; 'substituted by glass'};
            app.PackagingpapertonnessubstitutedbyglassEditField = uieditfield(app.InitialDemandsperyearPanel, 'numeric');
            app.PackagingpapertonnessubstitutedbyglassEditField.Limits = [0 Inf];
            app.PackagingpapertonnessubstitutedbyglassEditField.ValueDisplayFormat = '%.0f';
            app.PackagingpapertonnessubstitutedbyglassEditField.Value = 24000000;
            app.PackagingpapertonnessubstitutedbyaluminiumEditFieldLabel = uilabel(app.InitialDemandsperyearPanel);
            app.PackagingpapertonnessubstitutedbyaluminiumEditFieldLabel.Text = {'Packaging paper (tonnes), '; 'substituted by aluminium'};
            app.PackagingpapertonnessubstitutedbyaluminiumEditField = uieditfield(app.InitialDemandsperyearPanel, 'numeric');
            app.PackagingpapertonnessubstitutedbyaluminiumEditField.Limits = [0 Inf];
            app.PackagingpapertonnessubstitutedbyaluminiumEditField.ValueDisplayFormat = '%.0f';
            app.PackagingpapertonnessubstitutedbyaluminiumEditField.Value = 24000000;
            app.GraphicpapertonnesEditFieldLabel = uilabel(app.InitialDemandsperyearPanel);
            app.GraphicpapertonnesEditFieldLabel.Text = 'Graphic paper (tonnes)';
            app.GraphicpapertonnesEditField = uieditfield(app.InitialDemandsperyearPanel, 'numeric');
            app.GraphicpapertonnesEditField.Limits = [0 Inf];
            app.GraphicpapertonnesEditField.ValueDisplayFormat = '%.0f';
            app.GraphicpapertonnesEditField.Value = 90760000;
            app.TissuepapertonnesEditFieldLabel = uilabel(app.InitialDemandsperyearPanel);
            app.TissuepapertonnesEditFieldLabel.Text = 'Tissue paper (tonnes)';
            app.TissuepapertonnesEditField = uieditfield(app.InitialDemandsperyearPanel, 'numeric');
            app.TissuepapertonnesEditField.Limits = [0 Inf];
            app.TissuepapertonnesEditField.ValueDisplayFormat = '%.0f';
            app.TissuepapertonnesEditField.Value = 3240000;
            app.DefaultvaluesLabel = uilabel(app.InitialDemandsperyearPanel);
            app.DefaultvaluesLabel.Text = 'Default values';
            app.DemandSwitch = uiswitch(app.InitialDemandsperyearPanel, 'slider');
            app.DemandSwitch.Items = {'Predicted demands', 'Constant demands'};
            app.DemandSwitch.Value = 'Predicted demands';
            app.RunthesimulationButton = uibutton(app.LeftPanel, 'push');
            app.RunthesimulationButton.ButtonPushedFcn = createCallbackFcn(app, @RunTheSimulationButtonPushed, true);
            app.RunthesimulationButton.Text = 'Run the simulation';
            app.ForestDetailsPanel = uipanel(app.LeftPanel);
            app.ForestDetailsPanel.Title = 'Forest Details';
            app.InitialvolumeMm3EditFieldLabel = uilabel(app.ForestDetailsPanel);
            app.InitialvolumeMm3EditFieldLabel.Text = 'Initial volume (M m3)';
            app.InitialvolumeMm3EditField = uieditfield(app.ForestDetailsPanel, 'numeric');
            app.InitialvolumeMm3EditField.Limits = [0 Inf];
            app.InitialvolumeMm3EditField.Value = 2409;
            app.AreaMhaEditFieldLabel = uilabel(app.ForestDetailsPanel);
            app.AreaMhaEditFieldLabel.Text = 'Area (M ha)';
            app.AreaMhaEditField = uieditfield(app.ForestDetailsPanel, 'numeric');
            app.AreaMhaEditField.Limits = [0 Inf];
            app.AreaMhaEditField.Value = 20.276;
            app.InitialageyearsEditFieldLabel = uilabel(app.ForestDetailsPanel);
            app.InitialageyearsEditFieldLabel.Text = 'Initial age (years)';
            app.InitialageyearsEditField = uieditfield(app.ForestDetailsPanel, 'numeric');
            app.InitialageyearsEditField.Limits = [0 200];
            app.InitialageyearsEditField.Value = 61.5;
            app.ForentireFinnishforestryusethedefaultvaluesbelowLabel = uilabel(app.ForestDetailsPanel);
            app.ForentireFinnishforestryusethedefaultvaluesbelowLabel.Text = {'For entire Finnish forestry,'; 'use the default values below.'};
            app.RightPanel = uipanel(app.GridLayout);
            app.TabGroup2 = uitabgroup(app.RightPanel);
            app.MainPlotsTab = uitab(app.TabGroup2);
            app.MainPlotsTab.Title = 'Main Plots';
            app.PrincipalInterestUIAxes = uiaxes(app.MainPlotsTab);
            title(app.PrincipalInterestUIAxes, 'Finnish Forest Volume')
            xlabel(app.PrincipalInterestUIAxes, 'Time (Years)')
            ylabel(app.PrincipalInterestUIAxes, 'Volume (m^3)')
            app.PrincipalInterestUIAxes.XTick = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.PrincipalInterestUIAxes.XTickLabel = {'0'; '10'; '20'; '30'; '40'; '50'; '60'; '70'; '80'; '90'; '100'};
            app.PrincipalInterestUIAxes_14 = uiaxes(app.MainPlotsTab);
            title(app.PrincipalInterestUIAxes_14, 'Average Forest Age')
            xlabel(app.PrincipalInterestUIAxes_14, 'Time (Years)')
            ylabel(app.PrincipalInterestUIAxes_14, 'Age (Years)')
            app.PrincipalInterestUIAxes_14.XTick = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.PrincipalInterestUIAxes_14.XTickLabel = {'0'; '10'; '20'; '30'; '40'; '50'; '60'; '70'; '80'; '90'; '100'};
            app.PrincipalInterestUIAxes_16 = uiaxes(app.MainPlotsTab);
            title(app.PrincipalInterestUIAxes_16, 'Annual Drain')
            xlabel(app.PrincipalInterestUIAxes_16, 'Time (Years)')
            ylabel(app.PrincipalInterestUIAxes_16, 'Volume (m^3/year)')
            app.PrincipalInterestUIAxes_16.XTick = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.PrincipalInterestUIAxes_16.XTickLabel = {'0'; '10'; '20'; '30'; '40'; '50'; '60'; '70'; '80'; '90'; '100'};
            app.PrincipalInterestUIAxes_2 = uiaxes(app.MainPlotsTab);
            title(app.PrincipalInterestUIAxes_2, 'Atmospheric CO_2')
            xlabel(app.PrincipalInterestUIAxes_2, 'Time (Years)')
            ylabel(app.PrincipalInterestUIAxes_2, 'Tonnes of CO_2')
            app.PrincipalInterestUIAxes_2.XTick = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.PrincipalInterestUIAxes_2.XTickLabel = {'0'; '10'; '20'; '30'; '40'; '50'; '60'; '70'; '80'; '90'; '100'};
            app.ExogenousCO2EnergyTab = uitab(app.TabGroup2);
            app.ExogenousCO2EnergyTab.Title = 'Exogenous CO2 & Energy';
            app.PrincipalInterestUIAxes_6 = uiaxes(app.ExogenousCO2EnergyTab);
            title(app.PrincipalInterestUIAxes_6, 'Energy Recovery CO_2')
            xlabel(app.PrincipalInterestUIAxes_6, 'Time (Years)')
            ylabel(app.PrincipalInterestUIAxes_6, 'Tonnes of CO_2')
            app.PrincipalInterestUIAxes_6.XTick = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.PrincipalInterestUIAxes_6.XTickLabel = {'0'; '10'; '20'; '30'; '40'; '50'; '60'; '70'; '80'; '90'; '100'};
            app.PrincipalInterestUIAxes_5 = uiaxes(app.ExogenousCO2EnergyTab);
            title(app.PrincipalInterestUIAxes_5, 'Manufacturing and Waste Handling CO_2')
            xlabel(app.PrincipalInterestUIAxes_5, 'Time (Years)')
            ylabel(app.PrincipalInterestUIAxes_5, 'Tonnes of CO_2')
            app.PrincipalInterestUIAxes_5.XTick = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.PrincipalInterestUIAxes_5.XTickLabel = {'0'; '10'; '20'; '30'; '40'; '50'; '60'; '70'; '80'; '90'; '100'};
            app.PrincipalInterestUIAxes_4 = uiaxes(app.ExogenousCO2EnergyTab);
            title(app.PrincipalInterestUIAxes_4, 'Energy Recovered')
            xlabel(app.PrincipalInterestUIAxes_4, 'Time (Years)')
            ylabel(app.PrincipalInterestUIAxes_4, 'kWh')
            app.PrincipalInterestUIAxes_4.XTick = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.PrincipalInterestUIAxes_4.XTickLabel = {'0'; '10'; '20'; '30'; '40'; '50'; '60'; '70'; '80'; '90'; '100'};
            app.PrincipalInterestUIAxes_17 = uiaxes(app.ExogenousCO2EnergyTab);
            title(app.PrincipalInterestUIAxes_17, 'Incineration CO_2')
            xlabel(app.PrincipalInterestUIAxes_17, 'Time (Years)')
            ylabel(app.PrincipalInterestUIAxes_17, 'Tonnes of CO_2')
            app.PrincipalInterestUIAxes_17.XTick = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.PrincipalInterestUIAxes_17.XTickLabel = {'0'; '10'; '20'; '30'; '40'; '50'; '60'; '70'; '80'; '90'; '100'};
            app.ProductDemandandSupplyTab = uitab(app.TabGroup2);
            app.ProductDemandandSupplyTab.Title = 'Product Demand and Supply';
            app.PrincipalInterestUIAxes_20 = uiaxes(app.ProductDemandandSupplyTab);
            title(app.PrincipalInterestUIAxes_20, 'Sawn Wood and Plywood')
            xlabel(app.PrincipalInterestUIAxes_20, 'Time (Years)')
            ylabel(app.PrincipalInterestUIAxes_20, 'm^3')
            app.PrincipalInterestUIAxes_20.XTick = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.PrincipalInterestUIAxes_20.XTickLabel = {'0'; '10'; '20'; '30'; '40'; '50'; '60'; '70'; '80'; '90'; '100'};
            app.PrincipalInterestUIAxes_21 = uiaxes(app.ProductDemandandSupplyTab);
            title(app.PrincipalInterestUIAxes_21, 'Packaging Paper')
            xlabel(app.PrincipalInterestUIAxes_21, 'Time (Years)')
            ylabel(app.PrincipalInterestUIAxes_21, 'Tonnes')
            app.PrincipalInterestUIAxes_21.XTick = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.PrincipalInterestUIAxes_21.XTickLabel = {'0'; '10'; '20'; '30'; '40'; '50'; '60'; '70'; '80'; '90'; '100'};
            app.PrincipalInterestUIAxes_22 = uiaxes(app.ProductDemandandSupplyTab);
            title(app.PrincipalInterestUIAxes_22, 'Graphic Paper')
            xlabel(app.PrincipalInterestUIAxes_22, 'Time (Years)')
            ylabel(app.PrincipalInterestUIAxes_22, 'Tonnes')
            app.PrincipalInterestUIAxes_22.XTick = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.PrincipalInterestUIAxes_22.XTickLabel = {'0'; '10'; '20'; '30'; '40'; '50'; '60'; '70'; '80'; '90'; '100'};
            app.PrincipalInterestUIAxes_23 = uiaxes(app.ProductDemandandSupplyTab);
            title(app.PrincipalInterestUIAxes_23, 'Tissue Paper')
            xlabel(app.PrincipalInterestUIAxes_23, 'Time (Years)')
            ylabel(app.PrincipalInterestUIAxes_23, 'Tonnes')
            app.PrincipalInterestUIAxes_23.XTick = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.PrincipalInterestUIAxes_23.XTickLabel = {'0'; '10'; '20'; '30'; '40'; '50'; '60'; '70'; '80'; '90'; '100'};
            app.InformationTab = uitab(app.TabGroup2);
            app.InformationTab.Title = 'Information';
            app.RecyclingRatesfromTilastokeskus2018Panel = uipanel(app.InformationTab);
            app.RecyclingRatesfromTilastokeskus2018Panel.Title = 'Recycling Rates from Tilastokeskus (2018)';
            app.HarvestinglimitoftotalforestEditFieldLabel_2 = uilabel(app.RecyclingRatesfromTilastokeskus2018Panel);
            app.HarvestinglimitoftotalforestEditFieldLabel_2.Text = 'Wood';
            app.HarvestinglimitoftotalforestEditFieldLabel_18 = uilabel(app.RecyclingRatesfromTilastokeskus2018Panel);
            app.HarvestinglimitoftotalforestEditFieldLabel_18.Text = 'Material';
            app.HarvestinglimitoftotalforestEditFieldLabel_22 = uilabel(app.RecyclingRatesfromTilastokeskus2018Panel);
            app.HarvestinglimitoftotalforestEditFieldLabel_22.Text = 'Paper';
            app.HarvestinglimitoftotalforestEditFieldLabel_23 = uilabel(app.RecyclingRatesfromTilastokeskus2018Panel);
            app.HarvestinglimitoftotalforestEditFieldLabel_23.Text = 'Plastic';
            app.HarvestinglimitoftotalforestEditFieldLabel_24 = uilabel(app.RecyclingRatesfromTilastokeskus2018Panel);
            app.HarvestinglimitoftotalforestEditFieldLabel_24.Text = 'Glass';
            app.HarvestinglimitoftotalforestEditFieldLabel_25 = uilabel(app.RecyclingRatesfromTilastokeskus2018Panel);
            app.HarvestinglimitoftotalforestEditFieldLabel_25.Text = 'Metal';
            app.HarvestinglimitoftotalforestEditFieldLabel_26 = uilabel(app.RecyclingRatesfromTilastokeskus2018Panel);
            app.HarvestinglimitoftotalforestEditFieldLabel_26.Text = {'Material'; 'Recovery'};
            app.HarvestinglimitoftotalforestEditFieldLabel_27 = uilabel(app.RecyclingRatesfromTilastokeskus2018Panel);
            app.HarvestinglimitoftotalforestEditFieldLabel_27.Text = {'Energy'; 'Recovery'};
            app.HarvestinglimitoftotalforestEditFieldLabel_28 = uilabel(app.RecyclingRatesfromTilastokeskus2018Panel);
            app.HarvestinglimitoftotalforestEditFieldLabel_28.Text = 'Incineration';
            app.HarvestinglimitoftotalforestEditFieldLabel_29 = uilabel(app.RecyclingRatesfromTilastokeskus2018Panel);
            app.HarvestinglimitoftotalforestEditFieldLabel_29.Text = '0.493';
            app.HarvestinglimitoftotalforestEditFieldLabel_30 = uilabel(app.RecyclingRatesfromTilastokeskus2018Panel);
            app.HarvestinglimitoftotalforestEditFieldLabel_30.Text = '0.929';
            app.HarvestinglimitoftotalforestEditFieldLabel_31 = uilabel(app.RecyclingRatesfromTilastokeskus2018Panel);
            app.HarvestinglimitoftotalforestEditFieldLabel_31.Text = '0.631';
            app.HarvestinglimitoftotalforestEditFieldLabel_32 = uilabel(app.RecyclingRatesfromTilastokeskus2018Panel);
            app.HarvestinglimitoftotalforestEditFieldLabel_32.Text = '0.887';
            app.HarvestinglimitoftotalforestEditFieldLabel_33 = uilabel(app.RecyclingRatesfromTilastokeskus2018Panel);
            app.HarvestinglimitoftotalforestEditFieldLabel_33.Text = '0.999';
            app.HarvestinglimitoftotalforestEditFieldLabel_34 = uilabel(app.RecyclingRatesfromTilastokeskus2018Panel);
            app.HarvestinglimitoftotalforestEditFieldLabel_34.Text = '0.507';
            app.HarvestinglimitoftotalforestEditFieldLabel_35 = uilabel(app.RecyclingRatesfromTilastokeskus2018Panel);
            app.HarvestinglimitoftotalforestEditFieldLabel_35.Text = '0.071';
            app.HarvestinglimitoftotalforestEditFieldLabel_36 = uilabel(app.RecyclingRatesfromTilastokeskus2018Panel);
            app.HarvestinglimitoftotalforestEditFieldLabel_36.Text = '0.360';
            app.HarvestinglimitoftotalforestEditFieldLabel_37 = uilabel(app.RecyclingRatesfromTilastokeskus2018Panel);
            app.HarvestinglimitoftotalforestEditFieldLabel_37.Text = '0.112';
            app.HarvestinglimitoftotalforestEditFieldLabel_38 = uilabel(app.RecyclingRatesfromTilastokeskus2018Panel);
            app.HarvestinglimitoftotalforestEditFieldLabel_38.Text = '0';
            app.HarvestinglimitoftotalforestEditFieldLabel_39 = uilabel(app.RecyclingRatesfromTilastokeskus2018Panel);
            app.HarvestinglimitoftotalforestEditFieldLabel_39.Text = '0';
            app.HarvestinglimitoftotalforestEditFieldLabel_40 = uilabel(app.RecyclingRatesfromTilastokeskus2018Panel);
            app.HarvestinglimitoftotalforestEditFieldLabel_40.Text = '0';
            app.HarvestinglimitoftotalforestEditFieldLabel_41 = uilabel(app.RecyclingRatesfromTilastokeskus2018Panel);
            app.HarvestinglimitoftotalforestEditFieldLabel_41.Text = '0.008';
            app.HarvestinglimitoftotalforestEditFieldLabel_42 = uilabel(app.RecyclingRatesfromTilastokeskus2018Panel);
            app.HarvestinglimitoftotalforestEditFieldLabel_42.Text = '0.001';
            app.HarvestinglimitoftotalforestEditFieldLabel_43 = uilabel(app.RecyclingRatesfromTilastokeskus2018Panel);
            app.HarvestinglimitoftotalforestEditFieldLabel_43.Text = '0.001';
            app.RecyclingEmissionsfromDahlboetal2011Panel = uipanel(app.InformationTab);
            app.RecyclingEmissionsfromDahlboetal2011Panel.Title = 'Recycling Emissions from Dahlbo et al. (2011)';
            app.HarvestinglimitoftotalforestEditFieldLabel_44 = uilabel(app.RecyclingEmissionsfromDahlboetal2011Panel);
            app.HarvestinglimitoftotalforestEditFieldLabel_44.Text = 'Wood';
            app.HarvestinglimitoftotalforestEditFieldLabel_45 = uilabel(app.RecyclingEmissionsfromDahlboetal2011Panel);
            app.HarvestinglimitoftotalforestEditFieldLabel_45.Text = 'Material';
            app.HarvestinglimitoftotalforestEditFieldLabel_46 = uilabel(app.RecyclingEmissionsfromDahlboetal2011Panel);
            app.HarvestinglimitoftotalforestEditFieldLabel_46.Text = 'Paper';
            app.HarvestinglimitoftotalforestEditFieldLabel_47 = uilabel(app.RecyclingEmissionsfromDahlboetal2011Panel);
            app.HarvestinglimitoftotalforestEditFieldLabel_47.Text = 'Plastic';
            app.HarvestinglimitoftotalforestEditFieldLabel_48 = uilabel(app.RecyclingEmissionsfromDahlboetal2011Panel);
            app.HarvestinglimitoftotalforestEditFieldLabel_48.Text = 'Glass';
            app.HarvestinglimitoftotalforestEditFieldLabel_49 = uilabel(app.RecyclingEmissionsfromDahlboetal2011Panel);
            app.HarvestinglimitoftotalforestEditFieldLabel_49.Text = 'Metal';
            app.HarvestinglimitoftotalforestEditFieldLabel_50 = uilabel(app.RecyclingEmissionsfromDahlboetal2011Panel);
            app.HarvestinglimitoftotalforestEditFieldLabel_50.Text = {'Carbon'; 'Emissions'};
            app.HarvestinglimitoftotalforestEditFieldLabel_53 = uilabel(app.RecyclingEmissionsfromDahlboetal2011Panel);
            app.HarvestinglimitoftotalforestEditFieldLabel_53.Text = '0';
            app.HarvestinglimitoftotalforestEditFieldLabel_54 = uilabel(app.RecyclingEmissionsfromDahlboetal2011Panel);
            app.HarvestinglimitoftotalforestEditFieldLabel_54.Text = '1.05';
            app.HarvestinglimitoftotalforestEditFieldLabel_55 = uilabel(app.RecyclingEmissionsfromDahlboetal2011Panel);
            app.HarvestinglimitoftotalforestEditFieldLabel_55.Text = '2.33';
            app.HarvestinglimitoftotalforestEditFieldLabel_56 = uilabel(app.RecyclingEmissionsfromDahlboetal2011Panel);
            app.HarvestinglimitoftotalforestEditFieldLabel_56.Text = '0.57';
            app.HarvestinglimitoftotalforestEditFieldLabel_57 = uilabel(app.RecyclingEmissionsfromDahlboetal2011Panel);
            app.HarvestinglimitoftotalforestEditFieldLabel_57.Text = '0.13';
            app.HarvestinglimitoftotalforestEditFieldLabel_58 = uilabel(app.RecyclingEmissionsfromDahlboetal2011Panel);
            app.HarvestinglimitoftotalforestEditFieldLabel_58.Text = 'tonnes per m3';
            app.HarvestinglimitoftotalforestEditFieldLabel_59 = uilabel(app.RecyclingEmissionsfromDahlboetal2011Panel);
            app.HarvestinglimitoftotalforestEditFieldLabel_59.Text = 'tonnes per tonne';
            app.HarvestinglimitoftotalforestEditFieldLabel_60 = uilabel(app.RecyclingEmissionsfromDahlboetal2011Panel);
            app.HarvestinglimitoftotalforestEditFieldLabel_60.Text = 'tonnes per tonne';
            app.HarvestinglimitoftotalforestEditFieldLabel_61 = uilabel(app.RecyclingEmissionsfromDahlboetal2011Panel);
            app.HarvestinglimitoftotalforestEditFieldLabel_61.Text = 'tonnes per tonne';
            app.HarvestinglimitoftotalforestEditFieldLabel_62 = uilabel(app.RecyclingEmissionsfromDahlboetal2011Panel);
            app.HarvestinglimitoftotalforestEditFieldLabel_62.Text = 'tonnes per tonne';
            app.Datafromexpertsfortheyear2019Panel = uipanel(app.InformationTab);
            app.Datafromexpertsfortheyear2019Panel.Title = 'Data from experts for the year 2019';
            app.HarvestinglimitoftotalforestEditFieldLabel_63 = uilabel(app.Datafromexpertsfortheyear2019Panel);
            app.HarvestinglimitoftotalforestEditFieldLabel_63.Text = {'Sawn'; 'Lumber'};
            app.HarvestinglimitoftotalforestEditFieldLabel_64 = uilabel(app.Datafromexpertsfortheyear2019Panel);
            app.HarvestinglimitoftotalforestEditFieldLabel_64.Text = 'Product';
            app.HarvestinglimitoftotalforestEditFieldLabel_65 = uilabel(app.Datafromexpertsfortheyear2019Panel);
            app.HarvestinglimitoftotalforestEditFieldLabel_65.Text = 'Plywood';
            app.HarvestinglimitoftotalforestEditFieldLabel_66 = uilabel(app.Datafromexpertsfortheyear2019Panel);
            app.HarvestinglimitoftotalforestEditFieldLabel_66.Text = {'Packaging'; 'and Specialty'};
            app.HarvestinglimitoftotalforestEditFieldLabel_67 = uilabel(app.Datafromexpertsfortheyear2019Panel);
            app.HarvestinglimitoftotalforestEditFieldLabel_67.Text = {'Graphic'; 'Paper'};
            app.HarvestinglimitoftotalforestEditFieldLabel_69 = uilabel(app.Datafromexpertsfortheyear2019Panel);
            app.HarvestinglimitoftotalforestEditFieldLabel_69.Text = {'Harvested'; 'Finnish'; 'Wood (m3)'};
            app.HarvestinglimitoftotalforestEditFieldLabel_87 = uilabel(app.Datafromexpertsfortheyear2019Panel);
            app.HarvestinglimitoftotalforestEditFieldLabel_87.Text = {'Tissue'; 'Paper'};
            app.HarvestinglimitoftotalforestEditFieldLabel_88 = uilabel(app.Datafromexpertsfortheyear2019Panel);
            app.HarvestinglimitoftotalforestEditFieldLabel_88.Text = 'Energy';
            app.HarvestinglimitoftotalforestEditFieldLabel_89 = uilabel(app.Datafromexpertsfortheyear2019Panel);
            app.HarvestinglimitoftotalforestEditFieldLabel_89.Text = {'Pulp'; 'Produced'};
            app.HarvestinglimitoftotalforestEditFieldLabel_90 = uilabel(app.Datafromexpertsfortheyear2019Panel);
            app.HarvestinglimitoftotalforestEditFieldLabel_90.Text = {'Exported'; 'Pulp'};
            app.HarvestinglimitoftotalforestEditFieldLabel_91 = uilabel(app.Datafromexpertsfortheyear2019Panel);
            app.HarvestinglimitoftotalforestEditFieldLabel_91.Text = 'Biofuels';
            app.HarvestinglimitoftotalforestEditFieldLabel_92 = uilabel(app.Datafromexpertsfortheyear2019Panel);
            app.HarvestinglimitoftotalforestEditFieldLabel_92.Text = '25256000';
            app.HarvestinglimitoftotalforestEditFieldLabel_93 = uilabel(app.Datafromexpertsfortheyear2019Panel);
            app.HarvestinglimitoftotalforestEditFieldLabel_93.Text = '2634000';
            app.HarvestinglimitoftotalforestEditFieldLabel_94 = uilabel(app.Datafromexpertsfortheyear2019Panel);
            app.HarvestinglimitoftotalforestEditFieldLabel_94.Text = '0';
            app.HarvestinglimitoftotalforestEditFieldLabel_95 = uilabel(app.Datafromexpertsfortheyear2019Panel);
            app.HarvestinglimitoftotalforestEditFieldLabel_95.Text = '6439000';
            app.HarvestinglimitoftotalforestEditFieldLabel_96 = uilabel(app.Datafromexpertsfortheyear2019Panel);
            app.HarvestinglimitoftotalforestEditFieldLabel_96.Text = '0';
            app.HarvestinglimitoftotalforestEditFieldLabel_97 = uilabel(app.Datafromexpertsfortheyear2019Panel);
            app.HarvestinglimitoftotalforestEditFieldLabel_97.Text = '7586000';
            app.HarvestinglimitoftotalforestEditFieldLabel_98 = uilabel(app.Datafromexpertsfortheyear2019Panel);
            app.HarvestinglimitoftotalforestEditFieldLabel_98.Text = '26750000';
            app.HarvestinglimitoftotalforestEditFieldLabel_99 = uilabel(app.Datafromexpertsfortheyear2019Panel);
            app.HarvestinglimitoftotalforestEditFieldLabel_99.Text = '13497470';
            app.HarvestinglimitoftotalforestEditFieldLabel_100 = uilabel(app.Datafromexpertsfortheyear2019Panel);
            app.HarvestinglimitoftotalforestEditFieldLabel_100.Text = '0';
            app.HarvestinglimitoftotalforestEditFieldLabel_101 = uilabel(app.Datafromexpertsfortheyear2019Panel);
            app.HarvestinglimitoftotalforestEditFieldLabel_101.Text = {'Production'; 'in Finland'};
            app.HarvestinglimitoftotalforestEditFieldLabel_102 = uilabel(app.Datafromexpertsfortheyear2019Panel);
            app.HarvestinglimitoftotalforestEditFieldLabel_102.Text = '12628000 (m3)';
            app.HarvestinglimitoftotalforestEditFieldLabel_103 = uilabel(app.Datafromexpertsfortheyear2019Panel);
            app.HarvestinglimitoftotalforestEditFieldLabel_103.Text = '1317000 (m3)';
            app.HarvestinglimitoftotalforestEditFieldLabel_104 = uilabel(app.Datafromexpertsfortheyear2019Panel);
            app.HarvestinglimitoftotalforestEditFieldLabel_104.Text = '4800000 (t)';
            app.HarvestinglimitoftotalforestEditFieldLabel_105 = uilabel(app.Datafromexpertsfortheyear2019Panel);
            app.HarvestinglimitoftotalforestEditFieldLabel_105.Text = '4538000 (t)';
            app.HarvestinglimitoftotalforestEditFieldLabel_106 = uilabel(app.Datafromexpertsfortheyear2019Panel);
            app.HarvestinglimitoftotalforestEditFieldLabel_106.Text = '162000 (t)';
            app.HarvestinglimitoftotalforestEditFieldLabel_107 = uilabel(app.Datafromexpertsfortheyear2019Panel);
            app.HarvestinglimitoftotalforestEditFieldLabel_107.Text = '15172000 (MWh)';
            app.HarvestinglimitoftotalforestEditFieldLabel_108 = uilabel(app.Datafromexpertsfortheyear2019Panel);
            app.HarvestinglimitoftotalforestEditFieldLabel_108.Text = '8300000 (t)';
            app.HarvestinglimitoftotalforestEditFieldLabel_109 = uilabel(app.Datafromexpertsfortheyear2019Panel);
            app.HarvestinglimitoftotalforestEditFieldLabel_109.Text = '4177000 (t)';
            app.HarvestinglimitoftotalforestEditFieldLabel_110 = uilabel(app.Datafromexpertsfortheyear2019Panel);
            app.HarvestinglimitoftotalforestEditFieldLabel_110.Text = '120000 (t)';
            app.HarvestinglimitoftotalforestEditFieldLabel_111 = uilabel(app.Datafromexpertsfortheyear2019Panel);
            app.HarvestinglimitoftotalforestEditFieldLabel_111.Text = {'Pulp'; 'Consumption'; '(tonnes)'};
            app.HarvestinglimitoftotalforestEditFieldLabel_112 = uilabel(app.Datafromexpertsfortheyear2019Panel);
            app.HarvestinglimitoftotalforestEditFieldLabel_112.Text = '0';
            app.HarvestinglimitoftotalforestEditFieldLabel_113 = uilabel(app.Datafromexpertsfortheyear2019Panel);
            app.HarvestinglimitoftotalforestEditFieldLabel_113.Text = '0';
            app.HarvestinglimitoftotalforestEditFieldLabel_114 = uilabel(app.Datafromexpertsfortheyear2019Panel);
            app.HarvestinglimitoftotalforestEditFieldLabel_114.Text = '0.37';
            app.HarvestinglimitoftotalforestEditFieldLabel_115 = uilabel(app.Datafromexpertsfortheyear2019Panel);
            app.HarvestinglimitoftotalforestEditFieldLabel_115.Text = '0.36';
            app.HarvestinglimitoftotalforestEditFieldLabel_116 = uilabel(app.Datafromexpertsfortheyear2019Panel);
            app.HarvestinglimitoftotalforestEditFieldLabel_116.Text = '0.68';
            app.HarvestinglimitoftotalforestEditFieldLabel_117 = uilabel(app.Datafromexpertsfortheyear2019Panel);
            app.HarvestinglimitoftotalforestEditFieldLabel_117.Text = '0';
            app.HarvestinglimitoftotalforestEditFieldLabel_118 = uilabel(app.Datafromexpertsfortheyear2019Panel);
            app.HarvestinglimitoftotalforestEditFieldLabel_118.Text = '0';
            app.HarvestinglimitoftotalforestEditFieldLabel_119 = uilabel(app.Datafromexpertsfortheyear2019Panel);
            app.HarvestinglimitoftotalforestEditFieldLabel_119.Text = '0';
            app.HarvestinglimitoftotalforestEditFieldLabel_120 = uilabel(app.Datafromexpertsfortheyear2019Panel);
            app.HarvestinglimitoftotalforestEditFieldLabel_120.Text = '0';
            app.HarvestinglimitoftotalforestEditFieldLabel_121 = uilabel(app.Datafromexpertsfortheyear2019Panel);
            app.HarvestinglimitoftotalforestEditFieldLabel_121.Text = {'Bark (b)'; 'or lignin'; '(l) loss'};
            app.HarvestinglimitoftotalforestEditFieldLabel_122 = uilabel(app.Datafromexpertsfortheyear2019Panel);
            app.HarvestinglimitoftotalforestEditFieldLabel_122.Text = '10% (b)';
            app.HarvestinglimitoftotalforestEditFieldLabel_123 = uilabel(app.Datafromexpertsfortheyear2019Panel);
            app.HarvestinglimitoftotalforestEditFieldLabel_123.Text = '10% (b)';
            app.HarvestinglimitoftotalforestEditFieldLabel_124 = uilabel(app.Datafromexpertsfortheyear2019Panel);
            app.HarvestinglimitoftotalforestEditFieldLabel_124.Text = '';
            app.HarvestinglimitoftotalforestEditFieldLabel_125 = uilabel(app.Datafromexpertsfortheyear2019Panel);
            app.HarvestinglimitoftotalforestEditFieldLabel_125.Text = '10% (b)';
            app.HarvestinglimitoftotalforestEditFieldLabel_126 = uilabel(app.Datafromexpertsfortheyear2019Panel);
            app.HarvestinglimitoftotalforestEditFieldLabel_126.Text = '';
            app.HarvestinglimitoftotalforestEditFieldLabel_127 = uilabel(app.Datafromexpertsfortheyear2019Panel);
            app.HarvestinglimitoftotalforestEditFieldLabel_127.Text = '';
            app.HarvestinglimitoftotalforestEditFieldLabel_128 = uilabel(app.Datafromexpertsfortheyear2019Panel);
            app.HarvestinglimitoftotalforestEditFieldLabel_128.Text = '23% (l)';
            app.HarvestinglimitoftotalforestEditFieldLabel_129 = uilabel(app.Datafromexpertsfortheyear2019Panel);
            app.HarvestinglimitoftotalforestEditFieldLabel_129.Text = '23% (l)';
            app.HarvestinglimitoftotalforestEditFieldLabel_130 = uilabel(app.Datafromexpertsfortheyear2019Panel);
            app.HarvestinglimitoftotalforestEditFieldLabel_130.Text = '';
            app.HarvestinglimitoftotalforestEditFieldLabel_131 = uilabel(app.Datafromexpertsfortheyear2019Panel);
            app.HarvestinglimitoftotalforestEditFieldLabel_131.Text = {'Manufacture and'; 'transport kg CO2'; 'per prod. unit'};
            app.HarvestinglimitoftotalforestEditFieldLabel_132 = uilabel(app.Datafromexpertsfortheyear2019Panel);
            app.HarvestinglimitoftotalforestEditFieldLabel_132.Text = '31';
            app.HarvestinglimitoftotalforestEditFieldLabel_133 = uilabel(app.Datafromexpertsfortheyear2019Panel);
            app.HarvestinglimitoftotalforestEditFieldLabel_133.Text = '1667.75';
            app.HarvestinglimitoftotalforestEditFieldLabel_134 = uilabel(app.Datafromexpertsfortheyear2019Panel);
            app.HarvestinglimitoftotalforestEditFieldLabel_134.Text = '373.75';
            app.HarvestinglimitoftotalforestEditFieldLabel_135 = uilabel(app.Datafromexpertsfortheyear2019Panel);
            app.HarvestinglimitoftotalforestEditFieldLabel_135.Text = '373.75';
            app.HarvestinglimitoftotalforestEditFieldLabel_136 = uilabel(app.Datafromexpertsfortheyear2019Panel);
            app.HarvestinglimitoftotalforestEditFieldLabel_136.Text = '1100';
            app.HarvestinglimitoftotalforestEditFieldLabel_137 = uilabel(app.Datafromexpertsfortheyear2019Panel);
            app.HarvestinglimitoftotalforestEditFieldLabel_137.Text = '0';
            app.HarvestinglimitoftotalforestEditFieldLabel_138 = uilabel(app.Datafromexpertsfortheyear2019Panel);
            app.HarvestinglimitoftotalforestEditFieldLabel_138.Text = '366.67';
            app.HarvestinglimitoftotalforestEditFieldLabel_139 = uilabel(app.Datafromexpertsfortheyear2019Panel);
            app.HarvestinglimitoftotalforestEditFieldLabel_139.Text = '366.67';
            app.HarvestinglimitoftotalforestEditFieldLabel_140 = uilabel(app.Datafromexpertsfortheyear2019Panel);
            app.HarvestinglimitoftotalforestEditFieldLabel_140.Text = '740';
            app.HarvestinglimitoftotalforestEditFieldLabel_141 = uilabel(app.Datafromexpertsfortheyear2019Panel);
            app.HarvestinglimitoftotalforestEditFieldLabel_141.Text = {'Recycling'; 'Cost'};
            app.HarvestinglimitoftotalforestEditFieldLabel_142 = uilabel(app.Datafromexpertsfortheyear2019Panel);
            app.HarvestinglimitoftotalforestEditFieldLabel_142.Text = '0';
            app.HarvestinglimitoftotalforestEditFieldLabel_143 = uilabel(app.Datafromexpertsfortheyear2019Panel);
            app.HarvestinglimitoftotalforestEditFieldLabel_143.Text = '0';
            app.HarvestinglimitoftotalforestEditFieldLabel_144 = uilabel(app.Datafromexpertsfortheyear2019Panel);
            app.HarvestinglimitoftotalforestEditFieldLabel_144.Text = '1050';
            app.HarvestinglimitoftotalforestEditFieldLabel_145 = uilabel(app.Datafromexpertsfortheyear2019Panel);
            app.HarvestinglimitoftotalforestEditFieldLabel_145.Text = '1050';
            app.HarvestinglimitoftotalforestEditFieldLabel_146 = uilabel(app.Datafromexpertsfortheyear2019Panel);
            app.HarvestinglimitoftotalforestEditFieldLabel_146.Text = '0';
            app.HarvestinglimitoftotalforestEditFieldLabel_147 = uilabel(app.Datafromexpertsfortheyear2019Panel);
            app.HarvestinglimitoftotalforestEditFieldLabel_147.Text = '0';
            app.HarvestinglimitoftotalforestEditFieldLabel_148 = uilabel(app.Datafromexpertsfortheyear2019Panel);
            app.HarvestinglimitoftotalforestEditFieldLabel_148.Text = '0';
            app.HarvestinglimitoftotalforestEditFieldLabel_149 = uilabel(app.Datafromexpertsfortheyear2019Panel);
            app.HarvestinglimitoftotalforestEditFieldLabel_149.Text = '0';
            app.HarvestinglimitoftotalforestEditFieldLabel_150 = uilabel(app.Datafromexpertsfortheyear2019Panel);
            app.HarvestinglimitoftotalforestEditFieldLabel_150.Text = '0';
            % Show the figure after all components are created
            app.SimulationofForestIndustrysCO2ImpactUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI_carboncycle

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.SimulationofForestIndustrysCO2ImpactUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SimulationofForestIndustrysCO2ImpactUIFigure)
        end
    end
end